classdef DAQ_App_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        EMGGridLayout                 matlab.ui.container.Tab
        EMGAxes_4                     matlab.ui.control.UIAxes
        EMGAxes_3                     matlab.ui.control.UIAxes
        EMGAxes_2                     matlab.ui.control.UIAxes
        EMGAxes_1                     matlab.ui.control.UIAxes
        AnalogGridLayout              matlab.ui.container.Tab
        AnalogAxes_2                  matlab.ui.control.UIAxes
        AnalogAxes_1                  matlab.ui.control.UIAxes
        CombinedGridLayout            matlab.ui.container.Tab
        CombinedAxes                  matlab.ui.control.UIAxes
        UIPanel                       matlab.ui.container.Panel
        ThresholdValueEditFieldLabel  matlab.ui.control.Label
        ThresholdValueEditField       matlab.ui.control.EditField
        LabelCheckBox                 matlab.ui.control.CheckBox
        StatusLabel                   matlab.ui.control.Label
        YMinEditField                 matlab.ui.control.NumericEditField
        YminLabel                     matlab.ui.control.Label
        YMaxEditField                 matlab.ui.control.NumericEditField
        YmaxLabel                     matlab.ui.control.Label
        EMGChannelListBox             matlab.ui.control.ListBox
        EMGChannelsLabel              matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        InitializeButton              matlab.ui.control.Button
        PathStatusLabel               matlab.ui.control.Label
        SelectPathButton              matlab.ui.control.Button
        SubjectIDEditField            matlab.ui.control.EditField
        SubjectIDLabel                matlab.ui.control.Label
        FileStringEditField           matlab.ui.control.EditField
        SessionNameLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        % DAQ and Dragonfly Objects
        dq               % DAQ object for data acquisition
        DF               % Dragonfly object
        Fs = 2500        % Sampling rate

        % File I/O and Metadata
        SubID            % Subject ID
        Filestr          % File string for session name
        TrlNum = 1       % Trial number, starts at 1
        pathname         % Directory to save data
        meta_path        % Directory for metadata
        dataBuffer       % Cell array to buffer data in memory
        OPfile
        
        % App State
        isInitialized = false % Flag to check if app is ready
        
        % Plotting Handles and Buffers
        emgPlotHandles   % Handles for the 4 EMG line plots
        analogPlotHandles % Handles for the 16 analog line plots
        plotBuffer       % FIXED-SIZE circular buffer for plotting
        timeBuffer       % FIXED-SIZE time vector for the plot buffer
        emgAxes          % Handles to the EMG UIAxes
        analogAxes       % Handles to the Analog UIAxes
        fid

        EMG_CH
        muscle_labels
        analog_input_labels
        Blp                   % Butterworth filter coefficient (Low-pass)
        Alp                   % Butterworth filter coefficient (Low-pass)
        B_notch               % Butterworth filter coefficient (Notch)
        A_notch               % Butterworth filter coefficient (Notch)
        emgThresholdHandles   % Handles for the EMG threshold lines
        emgLabelHandles       % Handles for the EMG value text labels
        combinedPlotHandles  % Handles for the 6 combined line plots
    end

    methods (Access = private)

        function setupPlots(app)
            % Pre-allocate handles
            app.emgPlotHandles = gobjects(1, 4);
            app.emgThresholdHandles = gobjects(1, 4);
            app.analogPlotHandles = gobjects(1, 2);
            app.emgLabelHandles = gobjects(1, 4);
            app.combinedPlotHandles = gobjects(1, 6); % 4 EMG + 2 Analog

            % Setup individual EMG plots
            for i = 1:4
                hold(app.emgAxes(i), 'on'); 
                app.emgPlotHandles(i) = plot(app.emgAxes(i), NaN, NaN, 'b-'); 
                app.emgThresholdHandles(i) = yline(app.emgAxes(i), 0, 'r--', 'Visible', 'off');
                app.emgLabelHandles(i) = text(app.emgAxes(i), 0, 0, '', 'Visible', 'off', 'FontSize', 10);
                hold(app.emgAxes(i), 'off');
            end

            % Setup individual Analog plots
            for i = 1:2
                % Set titles for Analog Axes from labels
                title(app.analogAxes(i), app.analog_input_labels{i});
                app.analogPlotHandles(i) = plot(app.analogAxes(i), NaN, NaN, 'r-');
            end

            hold(app.CombinedAxes, 'on');
            % Define colors and styles for the 6 lines for better visibility
            colors = {'#0072BD', '#D95319', '#EDB120', '#7E2F8E', '#77AC30', '#4DBEEE'}; % blue, orange, yellow, purple, green, light blue
            lineStyles = {'-', '-', '-', '-', '--', '--'}; % Solid for EMG, Dashed for Analog

            % Create plot objects for the 4 EMG channels
            for i = 1:4
                app.combinedPlotHandles(i) = plot(app.CombinedAxes, NaN, NaN, ...
                    'Color', colors{i}, 'LineStyle', lineStyles{i}, 'LineWidth', 1.5);
            end

            % Create plot objects for the first 2 Analog channels
            for i = 1:2
                app.combinedPlotHandles(4 + i) = plot(app.CombinedAxes, NaN, NaN, ...
                    'Color', colors{4 + i}, 'LineStyle', lineStyles{4 + i}, 'LineWidth', 1.5);
            end
            
            title(app.CombinedAxes, 'Combined EMG & Analog Signals');
            xlabel(app.CombinedAxes, 'Time (s)');
            ylabel(app.CombinedAxes, 'Amplitude');
            grid(app.CombinedAxes, 'off');
            hold(app.CombinedAxes, 'off');
        end

        function logBroadcastAndPlotData(app, src, ~)
            % 1. Read data from DAQ
            [data, timestamps, ~] = read(src, src.ScansAvailableFcnCount, "OutputFormat", "Matrix");
            
            % --- OPTIMIZATION 1: Write data directly to file ---
            dataToWrite = [timestamps, data]'; % Transpose for fwrite
            fwrite(app.fid, dataToWrite, 'double');
            
            % --- OPTIMIZATION 2: Update fixed-size plot buffer ---
            newSamples = size(data, 1);
            app.plotBuffer = [app.plotBuffer(newSamples+1:end, :); data];
            
            % --- OPTIMIZATION 3: Only update plots on the VISIBLE tab ---
            selectedTab = app.TabGroup.SelectedTab;

            if selectedTab == app.EMGGridLayout
                % --- Update EMG Tab Plots ---
                selected_emg_labels = app.EMGChannelListBox.Value;
                for i = 1:length(selected_emg_labels)
                    label_index = find(strcmp(app.muscle_labels, selected_emg_labels{i}));
                    if ~isempty(label_index)
                        ch_index = app.EMG_CH(label_index(1));
                        emgData = app.plotBuffer(:, ch_index) - mean(app.plotBuffer(:, ch_index), 'omitnan');
                        set(app.emgPlotHandles(i), 'XData', app.timeBuffer, 'YData', emgData);
                    end
                end

            elseif selectedTab == app.AnalogGridLayout
                % --- Update Analog Tab Plots ---
                for i = 1:2
                    set(app.analogPlotHandles(i), 'XData', app.timeBuffer, 'YData', app.plotBuffer(:, i));
                end

            elseif selectedTab == app.CombinedGridLayout
                % --- Update Combined Tab Plots ---
                % Update EMG lines
                selected_emg_labels = app.EMGChannelListBox.Value;
                for i = 1:length(selected_emg_labels)
                     label_index = find(strcmp(app.muscle_labels, selected_emg_labels{i}));
                     if ~isempty(label_index)
                        ch_index = app.EMG_CH(label_index(1));
                        emgData = app.plotBuffer(:, ch_index) - mean(app.plotBuffer(:, ch_index), 'omitnan');
                        set(app.combinedPlotHandles(i), 'XData', app.timeBuffer, 'YData', emgData);
                     end
                end
                % Update Analog lines
                for i = 1:2
                    set(app.combinedPlotHandles(4 + i), 'XData', app.timeBuffer, 'YData', app.plotBuffer(:, i));
                end
                axis(app.CombinedAxes, 'tight');
            end
            
            drawnow('limitrate');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if exist('CH_config.json', 'file')
                config_data = jsondecode(fileread('CH_config.json'));
                app.EMG_CH = config_data.EMG_CH;
                app.muscle_labels = config_data.muscle_labels;
                app.analog_input_labels = config_data.analog_input_labels;
            else
                app.EMG_CH = [17,27,29,23,41,43,37,47,57,51,61,63,65,75,77,71];
                app.muscle_labels = { ...
                    'L_APB', 'NC_1', 'L-FLEX', 'L_FDI', ...
                    'NC_2', 'NC_3', 'NC_4', 'NC_5', ...
                    'NC_6', 'NC_7', 'NC_8', 'NC_9', ...
                    'NC_10', 'NC_11', 'NC_12', 'NC_13'};
                app.analog_input_labels = ...
                    {'HUMAC Dir', 'HUMAC Vel', 'HUMAC Torque', 'HUMAC Pos', ...
                     'KINARM Task', 'KINARM Rec', 'KINARM Rep', 'VICON Rec', ...
                     'VICON Frame', 'Grip Trig', 'Grip Dyn', 'Pinch Dyn', ...
                     'Arduino Trig', 'Arduino Amp', 'Arduino Stim', 'TMS Trig'};
            end
            app.EMGGridLayout.Title = 'EMG Channels';
            app.AnalogGridLayout.Title = 'Analog Channels';
            app.emgAxes = [app.EMGAxes_1, app.EMGAxes_2, app.EMGAxes_3, app.EMGAxes_4];
            app.analogAxes = [app.AnalogAxes_1, app.AnalogAxes_2];
            app.EMGChannelListBox.Items = app.muscle_labels;
            app.EMGChannelListBox.Value = app.muscle_labels(1:4); % Default to first 4 muscles

            setupPlots(app);

            app.StartButton.Enable = 'off';
            app.StopButton.Enable = 'off';

            % Initialize to 1, will be updated when path is selected
            app.TrlNum = 1;
            app.PathStatusLabel.Text = sprintf('Next Trial: %d (Select Path)', app.TrlNum);
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            app.StatusLamp.Color = 'yellow';
            drawnow;
            
            % --- SAFER DAQ Cleanup ---
            % Release the specific DAQ object if it exists
            if ~isempty(app.dq) && isvalid(app.dq)
                stop(app.dq);
                app.dq = [];       % Deletes the handle
            end
            
            % --- DAQ Setup ---
            try
                daqreset;
                % Find DAQ device name
                d = daqlist("ni");
                if isempty(d)
                    error('No NI DAQ devices found.');
                end
                deviceID = d{1, "DeviceID"};
                disp(['Using DAQ device: ', deviceID]);
    
                app.dq = daq("ni");
                app.dq.Rate = app.Fs;
    
                % Add input channel to device interface
                analogChannels = addinput(app.dq, deviceID, 0:15, "Voltage"); % Analog channels
                EMGChannels = addinput(app.dq, deviceID, 16:79, "Voltage"); % EMG channels
    
                for i = 1:length(analogChannels)
                    analogChannels(i).TerminalConfig = 'SingleEndedNonReferenced'; % Adjust if needed
                    % analogChannels(i).Coupling = 'AC'; % Uncomment if AC coupling is needed
                end
                for i = 1:length(EMGChannels)
                    EMGChannels(i).TerminalConfig = 'SingleEnded'; % Adjust if needed
                    % EMGChannels(i).Coupling = 'AC'; % Uncomment if AC coupling is needed for EMG
                end

                app.dq.ScansAvailableFcnCount = app.Fs * 0.1; % Update every 100ms
                app.dq.ScansAvailableFcn = @(src, evt) logBroadcastAndPlotData(app, src, evt);
            catch ME
                app.StatusLamp.Color = 'red';
                errMsg = sprintf('DAQ Initialization Failed: %s', ME.message);
                uialert(app.UIFigure, errMsg, "DAQ Error");
                return;
            end
            
            % --- Create Butterworth Low-Pass Filter ---
            lp_cutoff = 50 / (app.Fs * 0.5); % 50 Hz cutoff
            N_order = 5;
            [app.Blp, app.Alp] = butter(N_order, lp_cutoff, 'low');

            % --- Create 60 Hz Notch Filter ---
            f0 = 60;  % Frequency to remove (Hz)
            Q = 35;   % Quality factor (controls the width of the notch)
            bw = f0 / Q;
            Wn = [f0 - bw/2, f0 + bw/2] / (app.Fs/2);
            [app.B_notch, app.A_notch] = butter(2, Wn, 'stop');


            % --- Finalize Initialization ---
            app.isInitialized = true;
            app.StartButton.Enable = 'on';  
            app.StatusLamp.Color = 'green';
            app.StatusLabel.Text = 'Ready';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if isempty(app.pathname)
                uialert(app.UIFigure, 'Please select a path to save the data before starting.', 'Path Not Selected');
                return;
            end
            if ~app.isInitialized
                uialert(app.UIFigure, "Please initialize the connections first.", "Error");
                return;
            end
            app.SubID = app.SubjectIDEditField.Value;
            app.Filestr = app.FileStringEditField.Value;
            timestamp = datestr(now, 'yyyymmdd_HHMMSS');
            filename = sprintf('%s_%s_%s_Trl%03d.bin', timestamp, app.SubID, app.Filestr, app.TrlNum);
            app.OPfile = fullfile(app.pathname, filename);
            try
                app.OPfile = fullfile(app.pathname, filename);
                app.fid = fopen(app.OPfile, 'w'); % 'w' for write
                if app.fid == -1
                    error('Could not open file for writing.');
                end
            catch ME
                uialert(app.UIFigure, ['File Error: ', ME.message], 'Error');
                return;
            end
            % --- OPTIMIZATION: Pre-allocate fixed-size buffers for plotting only ---
            bufferDuration = 2; % 2 seconds
            bufferSamples = bufferDuration * app.Fs;
            numChannels = numel(app.dq.Channels);
            app.plotBuffer = NaN(bufferSamples, numChannels);
            app.timeBuffer = (0:bufferSamples-1)' / app.Fs;
            
            % Clear plots for the new trial
            for i = 1:numel(app.emgPlotHandles), set(app.emgPlotHandles(i), 'XData', NaN, 'YData', NaN); end
            for i = 1:numel(app.analogPlotHandles), set(app.analogPlotHandles(i), 'XData', NaN, 'YData', NaN); end
            for i = 1:numel(app.combinedPlotHandles), set(app.combinedPlotHandles(i), 'XData', NaN, 'YData', NaN); end

            % Set the legend on the combined plot
            selected_emg = app.EMGChannelListBox.Value;
            legend_labels = {selected_emg{1:4}, app.analog_input_labels{1:2}};
            legend(app.CombinedAxes, legend_labels, 'Location', 'northeast', 'Interpreter', 'none');
            
            start(app.dq, 'Continuous');
            app.StatusLamp.Color = 'green';
            app.StartButton.Enable = 'off';
            app.StopButton.Enable = 'on';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if ~isempty(app.dq) && app.dq.Running
                stop(app.dq);
            end
            if ~isempty(app.fid) && app.fid ~= -1
                fclose(app.fid);
                app.fid = []; % Clear the file ID
                app.StatusLabel.Text = 'Saved.';
            else
                app.StatusLabel.Text = 'Save Error!';   
            end
            
            % --- Save Metadata (this part remains the same) ---
            % ... (all your meta_data creation and saving code remains here) ...
            meta_data = struct();
            meta_data.sub_id = app.SubID;
            meta_data.date = date;
            meta_data.timestamp =  datestr(now, 'HHMMSS');
            meta_data.exp_name = app.Filestr;
            meta_data.trl_num = app.TrlNum;
            meta_data.fs = app.Fs;
            meta_data.total_num_emg_ch = length(app.muscle_labels);
            meta_data.emg_ch_number = app.EMG_CH;
            meta_data.musc_labels = app.muscle_labels;
            meta_data.musc_names = app.muscle_labels;
            meta_data.updaterate = 0.1;
            meta_data.updaterate_unit = 'seconds';
            meta_data.stim = 0; 
            meta_data.analog_input_labels = app.analog_input_labels;
            meta_data.analog_in_ch = 1:16;
            meta_data.pulse_width = 200;
            meta_data.pulse_width_unit = 'micro_seconds';
            meta_data.duty_cycle = 100;
            meta_data.dutycycle_unit = 'percent';
            meta_data.total_analog_in_ch = 80;
            
            metadata_filename = sprintf('%s_%s_%s_METADATA_Trl%03d.mat', ...
                datestr(now, 'yyyymmdd_HHMMSS'), app.SubID, app.Filestr, app.TrlNum);
            if ~isempty(app.meta_path)
                save(fullfile(app.meta_path, metadata_filename), 'meta_data');
            end
            
            app.StatusLamp.Color = 'red';
            app.StartButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.TrlNum = app.TrlNum + 1;
            app.PathStatusLabel.Text = sprintf('Next Trial: %d', app.TrlNum);
        end

        % Button pushed function: SelectPathButton
        function SelectPathButtonPushed(app, event)
            selectedPath = uigetdir('D:\VOP_DBS\test_0821', 'Select path to save the data');
            if selectedPath ~= 0
                app.pathname = selectedPath;
                app.PathStatusLabel.Text = ['Path: ' app.pathname];
                app.meta_path = fullfile(app.pathname, 'metadata');
                if ~exist(app.meta_path, 'dir'), mkdir(app.meta_path); end
                try
                    app.TrlNum = get_nxt_trialnb(app.pathname);
                catch ME
                    app.TrlNum = 1; % Default fallback
                    warning(['Could not determine next trial number from path. Defaulting to 1. Error: ', ME.message]);
                end
                app.PathStatusLabel.Text = sprintf('Path: %s (Next Trial: %d)', app.pathname, app.TrlNum);
                app.StartButton.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIPanel = uipanel(app.UIFigure);
            app.UIPanel.Title = 'Panel';
            app.SessionNameLabel = uilabel(app.UIPanel);
            app.SessionNameLabel.Text = 'Session Name';
            app.FileStringEditField = uieditfield(app.UIPanel, 'text');
            app.SubjectIDLabel = uilabel(app.UIPanel);
            app.SubjectIDLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.UIPanel, 'text');
            app.SelectPathButton = uibutton(app.UIPanel, 'push');
            app.SelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButtonPushed, true);
            app.SelectPathButton.Text = 'Select Path';
            app.PathStatusLabel = uilabel(app.UIPanel);
            app.PathStatusLabel.Text = 'Path:';
            app.InitializeButton = uibutton(app.UIPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Connect';
            app.StartButton = uibutton(app.UIPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StatusLamp = uilamp(app.UIPanel);
            app.EMGChannelsLabel = uilabel(app.UIPanel);
            app.EMGChannelsLabel.Text = {'EMG'; 'Channels'};
            app.EMGChannelListBox = uilistbox(app.UIPanel);
            app.EMGChannelListBox.Items = {'1', '2', '3', '4'};
            app.EMGChannelListBox.Multiselect = 'on';
            app.EMGChannelListBox.Value = {};
            app.YmaxLabel = uilabel(app.UIPanel);
            app.YmaxLabel.Text = 'Y max';
            app.YMaxEditField = uieditfield(app.UIPanel, 'numeric');
            app.YMaxEditField.Value = 0.5;
            app.YminLabel = uilabel(app.UIPanel);
            app.YminLabel.Text = 'Y min';
            app.YMinEditField = uieditfield(app.UIPanel, 'numeric');
            app.YMinEditField.Value = -0.5;
            app.StatusLabel = uilabel(app.UIPanel);
            app.StatusLabel.Text = 'Status';
            app.LabelCheckBox = uicheckbox(app.UIPanel);
            app.LabelCheckBox.Text = 'Label';
            app.ThresholdValueEditField = uieditfield(app.UIPanel, 'text');
            app.ThresholdValueEditFieldLabel = uilabel(app.UIPanel);
            app.ThresholdValueEditFieldLabel.Text = 'Threshold';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EMGGridLayout = uitab(app.TabGroup);
            app.EMGGridLayout.Title = 'EMG Channels';
            app.EMGAxes_1 = uiaxes(app.EMGGridLayout);
            title(app.EMGAxes_1, 'EMG CH1')
            xlabel(app.EMGAxes_1, 'Time (s)')
            ylabel(app.EMGAxes_1, 'Amplitude')
            zlabel(app.EMGAxes_1, 'Z')
            app.EMGAxes_2 = uiaxes(app.EMGGridLayout);
            title(app.EMGAxes_2, 'EMG CH2')
            xlabel(app.EMGAxes_2, 'Time (s)')
            ylabel(app.EMGAxes_2, 'Amplitude')
            zlabel(app.EMGAxes_2, 'Z')
            app.EMGAxes_3 = uiaxes(app.EMGGridLayout);
            title(app.EMGAxes_3, 'EMG CH3')
            xlabel(app.EMGAxes_3, 'Time (s)')
            ylabel(app.EMGAxes_3, 'Amplitude')
            zlabel(app.EMGAxes_3, 'Z')
            app.EMGAxes_4 = uiaxes(app.EMGGridLayout);
            title(app.EMGAxes_4, 'EMG CH4')
            xlabel(app.EMGAxes_4, 'Time (s)')
            ylabel(app.EMGAxes_4, 'Amplitude')
            zlabel(app.EMGAxes_4, 'Z')
            app.AnalogGridLayout = uitab(app.TabGroup);
            app.AnalogGridLayout.Title = 'Analog Channels';
            app.AnalogAxes_1 = uiaxes(app.AnalogGridLayout);
            title(app.AnalogAxes_1, 'Title')
            xlabel(app.AnalogAxes_1, 'Time (s)')
            ylabel(app.AnalogAxes_1, 'Amplitude')
            zlabel(app.AnalogAxes_1, 'Z')
            app.AnalogAxes_2 = uiaxes(app.AnalogGridLayout);
            title(app.AnalogAxes_2, 'Title')
            xlabel(app.AnalogAxes_2, 'Time (s)')
            ylabel(app.AnalogAxes_2, 'Amplitude')
            zlabel(app.AnalogAxes_2, 'Z')
            app.CombinedGridLayout = uitab(app.TabGroup);
            app.CombinedGridLayout.Title = 'Combined';
            app.CombinedAxes = uiaxes(app.CombinedGridLayout);
            title(app.CombinedAxes, 'Title')
            xlabel(app.CombinedAxes, 'Time (s)')
            ylabel(app.CombinedAxes, 'Amplitude')
            zlabel(app.CombinedAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAQ_App_new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end